classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Switch                matlab.ui.control.Switch
        SwitchLabel           matlab.ui.control.Label
        StartButtonS          matlab.ui.control.StateButton
        Label                 matlab.ui.control.Label
        VeryloudLamp          matlab.ui.control.Lamp
        VeryloudLampLabel     matlab.ui.control.Label
        NoiseLevelGauge       matlab.ui.control.LinearGauge
        NoiseLevelGaugeLabel  matlab.ui.control.Label
        STATUSLabel           matlab.ui.control.Label
        NormalLamp            matlab.ui.control.Lamp
        NormalLampLabel       matlab.ui.control.Label
        loudLamp              matlab.ui.control.Lamp
        loudLampLabel         matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObj % Arduino
        Count =0;
        dbData = [];
    end
    
    methods (Access = private)
        
        function readData(app, src, ~)
            data = readline(src);
            app.dbData(end+1) = str2double(data);
            app.Count = app.Count + 1;
            disp(app.dbData);
            dbAllData = app.dbData;
            CC = app.Count;
            if app.Switch.Value == true
%                 axis(app.UIAxes,[0, 50, 0, 250]);
                dbValue = app.dbData(end);
                app.NoiseLevelGauge.Value = dbValue;
                app.NormalLamp.Color = [0.90, 0.90, 0.90];
                app.loudLamp.Color = [0.90, 0.90, 0.90];
                app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
                if dbValue < 80
                    app.NormalLamp.Color = 'green';
                elseif dbValue >= 80 && dbValue < 120
                    app.loudLamp.Color = 'yellow';
                elseif dbValue >= 120
                    app.VeryloudLamp.Color = 'red';
                end
                app.Label.Text = string(dbValue) + " " + "dB";
                if CC < 50
                    plot(app.UIAxes, dbAllData);
                else
                    plot(app.UIAxes, dbAllData(end-60:end));
                end
            else
                cla(app.UIAxes)
                app.NoiseLevelGauge.Value = 0;
                app.NormalLamp.Color = [0.90, 0.90, 0.90];
                app.loudLamp.Color = [0.90, 0.90, 0.90];
                app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
                app.Label.Text = "0" + " " + "dB";
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arduinoObj = serialport("/dev/cu.usbserial-0001", 9600);
            configureTerminator(app.arduinoObj, "CR/LF");
            flush(app.arduinoObj);
            configureCallback(app.arduinoObj,"terminator",@(src, event) readData(app, src, event));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            CC = app.Count;
            dbAllData = app.dbData;
            t = linspace(0, 5, 5);
%             plot(app.UIAxes, dbAllData);
            
            axis(app.UIAxes,[0, 60, 0, 250]);
            dbValue = app.dbData(end);
            app.NoiseLevelGauge.Value = dbValue;
            app.NormalLamp.Color = [0.90, 0.90, 0.90];
            app.loudLamp.Color = [0.90, 0.90, 0.90];
            app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
            if dbValue < 80
                app.NormalLamp.Color = 'green';
            elseif dbValue >= 80 && dbValue < 120
                app.loudLamp.Color = 'yellow';
            elseif dbValue >= 120
                app.VeryloudLamp.Color = 'red';
            end
            app.Label.Text = string(dbValue) + " " + "dB";
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            cla(app.UIAxes)
            app.NoiseLevelGauge.Value = 0;
            app.NormalLamp.Color = [0.90, 0.90, 0.90];
            app.loudLamp.Color = [0.90, 0.90, 0.90];
            app.VeryloudLamp.Color = [0.90, 0.90, 0.90];
            app.Label.Text = "0" + " " + "dB";
        end

        % Value changed function: StartButtonS
        function StartButtonSValueChanged(app, event)
           
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ADC Value ( dB )')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
            app.UIAxes.YTick = [0 25 50 75 100 125 150 175 200 225 250];
            app.UIAxes.YTickLabel = {'0'; '25'; '50'; '75'; '100'; '125'; '150'; '175'; '200'; '225'; '250'};
            colormap(app.UIAxes, 'copper')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.loudLampLabel = uilabel(app.UIFigure);
            app.loudLampLabel.Text = 'loud';
            app.loudLamp = uilamp(app.UIFigure);
            app.NormalLampLabel = uilabel(app.UIFigure);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.UIFigure);
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STATUS';
            app.NoiseLevelGaugeLabel = uilabel(app.UIFigure);
            app.NoiseLevelGaugeLabel.Text = 'Noise Level';
            app.NoiseLevelGauge = uigauge(app.UIFigure, 'linear');
            app.NoiseLevelGauge.Limits = [0 250];
            app.VeryloudLampLabel = uilabel(app.UIFigure);
            app.VeryloudLampLabel.Text = 'Very loud';
            app.VeryloudLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StartButtonS = uibutton(app.UIFigure, 'state');
            app.StartButtonS.ValueChangedFcn = createCallbackFcn(app, @StartButtonSValueChanged, true);
            app.StartButtonS.Text = 'Button2';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end